classdef chamberDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CancelButton          matlab.ui.control.Button
        DoneButton            matlab.ui.control.Button
        DcutPanel             matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        ParametersPanel       matlab.ui.container.Panel
        AVVEditField          matlab.ui.control.EditField
        AVVEditFieldLabel     matlab.ui.control.Label
        AWmEditField          matlab.ui.control.EditField
        AWmEditFieldLabel     matlab.ui.control.Label
        SRmEditField          matlab.ui.control.EditField
        SRmEditFieldLabel     matlab.ui.control.Label
        MPAEditField          matlab.ui.control.EditField
        MPAEditFieldLabel     matlab.ui.control.Label
        MRTEditField          matlab.ui.control.EditField
        MRTLabel              matlab.ui.control.Label
        MRFEditField          matlab.ui.control.EditField
        MRFLabel              matlab.ui.control.Label
        DepthmEditField       matlab.ui.control.EditField
        DepthmEditFieldLabel  matlab.ui.control.Label
        WidthmEditField       matlab.ui.control.EditField
        WidthmEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Parent % parent app
        AppChamber % chamber object
        RowNumber % index of chamber in thruster 
    end

    methods (Access = public)
        
        function plot2Dcut(app)
            ax = app.UIAxes;
            app.AppChamber.plot2D(ax);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent, transferData)
            app.Parent = parent;
            
            % default chamber
            if isempty(transferData.RowNumber)
                chamber = Chamber();
            else
                chamber = transferData.Chamber;
            end
            app.AppChamber = chamber;
            app.RowNumber = transferData.RowNumber;

            % set fields
            app.WidthmEditField.Value = num2str(chamber.Width);
            app.DepthmEditField.Value = num2str(chamber.Depth);
            app.MRFEditField.Value = num2str(chamber.MagentRegionFrom);
            app.MRTEditField.Value = num2str(chamber.MagnetRegionTo);
            app.MPAEditField.Value = num2str(chamber.MagnetPower);
            app.SRmEditField.Value = num2str(chamber.StartRadius);
            app.AWmEditField.Value = num2str(chamber.AnodeWidth);
            app.AVVEditField.Value = num2str(chamber.AnodeVoltage);

            % plot 2d cut of chamber 
            app.plot2Dcut();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if isempty(app.RowNumber)
                % add new chamber to thruster object 
                app.Parent.AppEnviroment.Thruster.Chambers{end+1} = app.AppChamber;
            else
                % reset new chamber of thruster
                app.Parent.AppEnviroment.Thruster.Chambers{app.RowNumber} = app.AppChamber;
            end

            % reset fields
            app.Parent.resetEnviromentInputFields();
            app.delete();
        end

        % Value changed function: WidthmEditField
        function WidthmEditFieldValueChanged(app, event)
            value = app.WidthmEditField.Value;
            % convert to number
            value = str2double(value);
            % update chamber 
            app.AppChamber.Width = value;
            app.WidthmEditField.Value = num2str(app.AppChamber.Width);
            % plot 2d cut of chamber 
            app.plot2Dcut();
        end

        % Value changed function: DepthmEditField
        function DepthmEditFieldValueChanged(app, event)
            value = app.DepthmEditField.Value;
            % convert to number
            value = str2double(value);
            % update chamber 
            app.AppChamber.Depth = value;
            app.DepthmEditField.Value = num2str(app.AppChamber.Depth);
            % plot 2d cut of chamber 
            app.plot2Dcut();
        end

        % Value changed function: MRFEditField
        function MRFEditFieldValueChanged(app, event)
            value = app.MRFEditField.Value;
            % convert to number
            value = str2double(value);
            % update chamber 
            app.AppChamber.MagentRegionFrom = value;
            app.MRFEditField.Value = num2str(app.AppChamber.MagentRegionFrom);
            % plot 2d cut of chamber 
            app.plot2Dcut();
        end

        % Value changed function: MRTEditField
        function MRTEditFieldValueChanged(app, event)
            value = app.MRTEditField.Value;
            % convert to number
            value = str2double(value);
            % update chamber 
            app.AppChamber.MagnetRegionTo = value;
            app.MRTEditField.Value = num2str(app.AppChamber.MagnetRegionTo);
            % plot 2d cut of chamber 
            app.plot2Dcut();
        end

        % Value changed function: MPAEditField
        function MPAEditFieldValueChanged(app, event)
            value = app.MPAEditField.Value;
            % convert to number
            value = str2double(value);
            % update chamber 
            app.AppChamber.MagnetPower = value;
            app.MPAEditField.Value = num2str(app.AppChamber.MagnetPower);
            % plot 2d cut of chamber 
            app.plot2Dcut();
        end

        % Value changed function: SRmEditField
        function SRmEditFieldValueChanged(app, event)
            value = app.SRmEditField.Value;
            % convert to number
            value = str2double(value);
            % update chamber 
            app.AppChamber.StartRadius = value;
            app.SRmEditField.Value = num2str(app.AppChamber.StartRadius);
            % plot 2d cut of chamber 
            app.plot2Dcut();
        end

        % Value changed function: AWmEditField
        function AWmEditFieldValueChanged(app, event)
            value = app.AWmEditField.Value;
            % convert to number
            value = str2double(value);
            % update chamber 
            app.AppChamber.AnodeWidth = value;
            app.AWmEditField.Value = num2str(app.AppChamber.AnodeWidth);
            % plot 2d cut of chamber 
            app.plot2Dcut();
        end

        % Value changed function: AVVEditField
        function AVVEditFieldValueChanged(app, event)
            value = app.AVVEditField.Value;
            % convert to number
            value = str2double(value);
            % update chamber 
            app.AppChamber.AnodeVoltage = value;
            app.AVVEditField.Value = num2str(app.AppChamber.AnodeVoltage);
            % plot 2d cut of chamber 
            app.plot2Dcut();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.WidthmEditFieldLabel = uilabel(app.ParametersPanel);
            app.WidthmEditFieldLabel.Text = 'Width (m):';
            app.WidthmEditField = uieditfield(app.ParametersPanel, 'text');
            app.WidthmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthmEditFieldValueChanged, true);
            app.DepthmEditFieldLabel = uilabel(app.ParametersPanel);
            app.DepthmEditFieldLabel.Text = 'Depth (m):';
            app.DepthmEditField = uieditfield(app.ParametersPanel, 'text');
            app.DepthmEditField.ValueChangedFcn = createCallbackFcn(app, @DepthmEditFieldValueChanged, true);
            app.MRFLabel = uilabel(app.ParametersPanel);
            app.MRFLabel.Text = 'MRF:';
            app.MRFEditField = uieditfield(app.ParametersPanel, 'text');
            app.MRFEditField.ValueChangedFcn = createCallbackFcn(app, @MRFEditFieldValueChanged, true);
            app.MRTLabel = uilabel(app.ParametersPanel);
            app.MRTLabel.Text = 'MRT:';
            app.MRTEditField = uieditfield(app.ParametersPanel, 'text');
            app.MRTEditField.ValueChangedFcn = createCallbackFcn(app, @MRTEditFieldValueChanged, true);
            app.MPAEditFieldLabel = uilabel(app.ParametersPanel);
            app.MPAEditFieldLabel.Text = 'MP (A):';
            app.MPAEditField = uieditfield(app.ParametersPanel, 'text');
            app.MPAEditField.ValueChangedFcn = createCallbackFcn(app, @MPAEditFieldValueChanged, true);
            app.SRmEditFieldLabel = uilabel(app.ParametersPanel);
            app.SRmEditFieldLabel.Text = 'SR (m):';
            app.SRmEditField = uieditfield(app.ParametersPanel, 'text');
            app.SRmEditField.ValueChangedFcn = createCallbackFcn(app, @SRmEditFieldValueChanged, true);
            app.AWmEditFieldLabel = uilabel(app.ParametersPanel);
            app.AWmEditFieldLabel.Text = 'AW (m):';
            app.AWmEditField = uieditfield(app.ParametersPanel, 'text');
            app.AWmEditField.ValueChangedFcn = createCallbackFcn(app, @AWmEditFieldValueChanged, true);
            app.AVVEditFieldLabel = uilabel(app.ParametersPanel);
            app.AVVEditFieldLabel.Text = 'AV (V):';
            app.AVVEditField = uieditfield(app.ParametersPanel, 'text');
            app.AVVEditField.ValueChangedFcn = createCallbackFcn(app, @AVVEditFieldValueChanged, true);
            app.DcutPanel = uipanel(app.UIFigure);
            app.DcutPanel.Title = '2D cut';
            app.UIAxes = uiaxes(app.DcutPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chamberDesign(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end