classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EnviromentTab                   matlab.ui.container.Tab
        Plot2DButton                    matlab.ui.control.Button
        Plot3DButton                    matlab.ui.control.Button
        GeneratemeshButton              matlab.ui.control.Button
        EnvRadiusmEditField             matlab.ui.control.EditField
        EnvRadiusmEditFieldLabel        matlab.ui.control.Label
        EnvHeightmEditField             matlab.ui.control.EditField
        EnvHeightLabel                  matlab.ui.control.Label
        ThrusterPanel                   matlab.ui.container.Panel
        FinalWidthmEditField            matlab.ui.control.EditField
        FinalWidthLabel                 matlab.ui.control.Label
        ChambersPanel                   matlab.ui.container.Panel
        DeleteButton                    matlab.ui.control.Button
        EditButton                      matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CathodePanel                    matlab.ui.container.Panel
        IsinmiddleCheckBox              matlab.ui.control.CheckBox
        HeightmEditField                matlab.ui.control.EditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        RadiusmEditField                matlab.ui.control.EditField
        RadiusmEditFieldLabel           matlab.ui.control.Label
        VoltageVEditField               matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        SolverTab                       matlab.ui.container.Tab
        BplotPanel                      matlab.ui.container.Panel
        filedongridButton_2             matlab.ui.control.Button
        dymEditField_2                  matlab.ui.control.NumericEditField
        dymEditField_2Label             matlab.ui.control.Label
        dxmEditField_2                  matlab.ui.control.NumericEditField
        dxmEditField_2Label             matlab.ui.control.Label
        feildonmeshButton_2             matlab.ui.control.Button
        meshButton_2                    matlab.ui.control.Button
        EplotPanel                      matlab.ui.container.Panel
        filedongridButton               matlab.ui.control.Button
        dymEditField                    matlab.ui.control.NumericEditField
        dymEditFieldLabel               matlab.ui.control.Label
        dxmEditField                    matlab.ui.control.NumericEditField
        dxLabel                         matlab.ui.control.Label
        feildonmeshButton               matlab.ui.control.Button
        meshButton                      matlab.ui.control.Button
        BParametersPanel                matlab.ui.container.Panel
        CorePermeabilityEditField       matlab.ui.control.NumericEditField
        CorePermeabilityEditFieldLabel  matlab.ui.control.Label
        SolveButton_2                   matlab.ui.control.Button
        HgradEditField_2                matlab.ui.control.NumericEditField
        HgradEditField_2Label           matlab.ui.control.Label
        HmaxmEditField_2                matlab.ui.control.NumericEditField
        HmaxmEditField_2Label           matlab.ui.control.Label
        HminmEditField_2                matlab.ui.control.NumericEditField
        HminmEditField_2Label           matlab.ui.control.Label
        DetailLengthmEditField_2        matlab.ui.control.NumericEditField
        DetailLengthmEditField_2Label   matlab.ui.control.Label
        EParametersPanel                matlab.ui.container.Panel
        SolveButton                     matlab.ui.control.Button
        HgradEditField                  matlab.ui.control.NumericEditField
        HgradLabel                      matlab.ui.control.Label
        HmaxmEditField                  matlab.ui.control.NumericEditField
        HmaxLabel                       matlab.ui.control.Label
        HminmEditField                  matlab.ui.control.NumericEditField
        HminLabel                       matlab.ui.control.Label
        DetailLengthmEditField          matlab.ui.control.NumericEditField
        DetailLengthLabel               matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        exportcsvButton                 matlab.ui.control.Button
        dymEditField_3                  matlab.ui.control.NumericEditField
        dymEditField_3Label             matlab.ui.control.Label
        dxmEditField_3                  matlab.ui.control.NumericEditField
        dxmEditField_3Label             matlab.ui.control.Label
        ExportresultsButton             matlab.ui.control.Button
        coefficientsPanel               matlab.ui.container.Panel
        ionexitvlocitymsEditField       matlab.ui.control.NumericEditField
        ionexitvlocitymsEditFieldLabel  matlab.ui.control.Label
        anodeefficienyEditField         matlab.ui.control.NumericEditField
        anodeefficienyEditFieldLabel    matlab.ui.control.Label
        computeButton                   matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        interpolationPanel              matlab.ui.container.Panel
        ByTEditField                    matlab.ui.control.NumericEditField
        ByTEditFieldLabel               matlab.ui.control.Label
        BxTEditField                    matlab.ui.control.NumericEditField
        BxTEditFieldLabel               matlab.ui.control.Label
        EyVmEditField                   matlab.ui.control.NumericEditField
        EyVmEditFieldLabel              matlab.ui.control.Label
        interpolateButton               matlab.ui.control.Button
        ExVmEditField                   matlab.ui.control.NumericEditField
        ExLabel                         matlab.ui.control.Label
        ycoordinatesmEditField          matlab.ui.control.NumericEditField
        ycoordinatesLabel               matlab.ui.control.Label
        xcoordinatesmEditField          matlab.ui.control.NumericEditField
        xcoordinatesLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        AppEnviroment % hall thruster enviroment object
        AppSolverE % electrostatic solver 
        AppSolverB % magneto static solver
        AppEC % efficientcy calculator object
    end
    
    
    methods (Access = public)
        
        function resetEnviromentInputFields(app)
            % cathode inputs 
            app.VoltageVEditField.Value = num2str(app.AppEnviroment.Thruster.Cathode.Voltage);
            app.RadiusmEditField.Value = num2str(app.AppEnviroment.Thruster.Cathode.Radius);
            app.HeightmEditField.Value = num2str(app.AppEnviroment.Thruster.Cathode.Height);
            app.IsinmiddleCheckBox.Value = app.AppEnviroment.Thruster.Cathode.IsInMiddle;

            % chambers 
            chambers = app.AppEnviroment.Thruster.Chambers;
            set(app.UITable, 'Data', []);
            for i = 1 : length(chambers)
                chamber = chambers{i};
                data = {chamber.Width, ...
                    chamber.Depth, ...
                    chamber.MagentRegionFrom, ...
                    chamber.MagnetRegionTo, ...
                    chamber.MagnetPower, ...
                    chamber.StartRadius, ...
                    chamber.AnodeWidth, ...
                    chamber.AnodeVoltage, ...
                    };
                app.addNewChamber(data);
            end
            app.ChambersPanel.Title = "Chamber (" + length(chambers) + ")";

            % final width
            app.FinalWidthmEditField.Value = num2str(app.AppEnviroment.Thruster.FinalWidth);

            % enviroment 
            app.EnvHeightmEditField.Value = num2str(app.AppEnviroment.Height);
            app.EnvRadiusmEditField.Value = num2str(app.AppEnviroment.Radius);
        end

        function addNewChamber(app, newData)
            % get table data and update new row at the end
            currentData = get(app.UITable, 'Data');
            newData = [currentData; newData];
            set(app.UITable, 'Data', newData);
        end
    end
    
    methods (Access = private)
        
        function disableComponents(app, state)
            % This function enables or disables all UI components in the app
            % 'state' should be 'off' to disable or 'on' to enable
    
            % Get all UI components in the app selected tab
            components = app.UIFigure.Children.SelectedTab.Children;
    
            % Loop through each component and set its 'Enable' property
            for i = 1:length(components)
                if isprop(components(i), 'Enable')
                    components(i).Enable = state;
                end
            end
        end
        
    end
      

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create default thruster
            app.AppEnviroment = Enviroment();

            % set fields 
            app.resetEnviromentInputFields();

            % efficiency calculator object 
            app.AppEC = EfficiencyCalculator([],[]);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % transfer data between apps
            TD = struct;
            TD.RowNumber = [];
            TD.Chamber = [];

            chamberDesign(app, TD);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            selectedRow = app.UITable.UserData;
            if isempty(selectedRow)
                msgbox("Please select chamber to delete")
            else
                answer = questdlg("Do you want to delete chamber " + selectedRow, ...
                    "delete chamber","yes", "no", "no");
                if answer == "yes"
                    if length(app.AppEnviroment.Thruster.Chambers) == 1
                        msgbox("Thruster must have one chamber at least")
                        return
                    end
                    % delete chamber from thruster
                    app.AppEnviroment.Thruster.Chambers(selectedRow) = [];
                    app.resetEnviromentInputFields();
                    app.UITable.UserData = [];
                end
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            % save selected row
            app.UITable.UserData = indices(1);
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            selectedRow = app.UITable.UserData;
            if isempty(selectedRow)
                msgbox("Please select chamber to edit")
            else
                TD = struct;
                TD.RowNumber = selectedRow;
                TD.Chamber = app.AppEnviroment.Thruster.Chambers{selectedRow};
                chamberDesign(app, TD);
            end
        end

        % Button pushed function: GeneratemeshButton
        function GeneratemeshButtonPushed(app, event)
            % Disable all UI components in the app
            h = msgbox('Please wait, generating mesh...', 'Wait', 'modal');
            disableComponents(app, 'off');
            pause(1);

            % generate mesh
            app.AppEnviroment = createGeometry3D(app.AppEnviroment);

            % Close the wait dialog
            pause(1);
            if ishandle(h)
                close(h);
            end
            disableComponents(app, 'on');
        end

        % Button pushed function: Plot3DButton
        function Plot3DButtonPushed(app, event)
            if isempty(app.AppEnviroment.Geometry3D)
                msgbox("There is no generated mesh")
                return
            end
            figure;
            plot3D(app.AppEnviroment)
        end

        % Value changed function: IsinmiddleCheckBox
        function IsinmiddleCheckBoxValueChanged(app, event)
            value = app.IsinmiddleCheckBox.Value;
            app.AppEnviroment.Thruster.Cathode.IsInMiddle = value;
        end

        % Button pushed function: Plot2DButton
        function Plot2DButtonPushed(app, event)
            figure;
            ax = axes();
            app.AppEnviroment.plot2D(ax);
        end

        % Value changed function: VoltageVEditField
        function VoltageVEditFieldValueChanged(app, event)
            value = app.VoltageVEditField.Value;
            
            app.AppEnviroment.Thruster.Cathode.Voltage = str2num(value);
        end

        % Value changed function: RadiusmEditField
        function RadiusmEditFieldValueChanged(app, event)
            value = app.RadiusmEditField.Value; 
            app.AppEnviroment.Thruster.Cathode.Radius = str2num(value);
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            value = app.HeightmEditField.Value;
            app.AppEnviroment.Thruster.Cathode.Height = str2num(value);
        end

        % Value changed function: FinalWidthmEditField
        function FinalWidthmEditFieldValueChanged(app, event)
            value = app.FinalWidthmEditField.Value;
            app.AppEnviroment.Thruster.FinalWidth = str2num(value);
        end

        % Value changed function: EnvHeightmEditField
        function EnvHeightmEditFieldValueChanged(app, event)
            value = app.EnvHeightmEditField.Value;
            app.AppEnviroment.Height = str2num(value);
        end

        % Value changed function: EnvRadiusmEditField
        function EnvRadiusmEditFieldValueChanged(app, event)
            value = app.EnvRadiusmEditField.Value;
            app.AppEnviroment.Radius = str2num(value);
        end

        % Callback function
        function MagnetostaticSolveButtonPushed(app, event)
            app.prepareSolver();
            app.AppSolver.solveB();
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            % Disable all UI components in the app
            h = msgbox('Please wait, solving electrostatics...', 'Wait', 'modal');
            disableComponents(app, 'off');
            pause(1);
            
            solver = SolverE(app.AppEnviroment);

            % set properties
            solver.DetailedLength = app.DetailLengthmEditField.Value;
            solver.Hmin = app.HminmEditField.Value;
            solver.Hmax = app.HmaxmEditField.Value;
            solver.Hgrad = app.HgradEditField.Value;

            % solve problem
            solver = solve(solver);
            solver.plotResults();

            % assign property into solver object
            app.AppSolverE = solver;

            % update efficiency calculator
            app.AppEC.ElectrostaticSolver = solver;

            % Close the wait dialog
            pause(1);
            if ishandle(h)
                close(h);
            end
            disableComponents(app, 'on');
        end

        % Button pushed function: SolveButton_2
        function SolveButton_2Pushed(app, event)
            % Disable all UI components in the app
            h = msgbox('Please wait, solving magnetostatics...', 'Wait', 'modal');
            disableComponents(app, 'off');
            pause(1);
            
            solver = SolverB(app.AppEnviroment);

            % set properties
            solver.DetailedLength = app.DetailLengthmEditField_2.Value;
            solver.Hmin = app.HminmEditField_2.Value;
            solver.Hmax = app.HmaxmEditField_2.Value;
            solver.Hgrad = app.HgradEditField_2.Value;
            solver.CorePermeability = app.CorePermeabilityEditField.Value;

            % solve problem
            solver = solve(solver);
            solver.plotResults();

            % assign property into solver object
            app.AppSolverB = solver;

            % update efficiency calculator
            app.AppEC.MagnetostaticSolver = solver;

            % Close the wait dialog
            pause(1);
            if ishandle(h)
                close(h);
            end
            disableComponents(app, 'on');
        end

        % Button pushed function: meshButton
        function meshButtonPushed(app, event)
            if isempty(app.AppSolverE)
                return
            end

            figure
            pdeplot(app.AppSolverE.Model.Mesh)
            hold on
            h = pdegplot(app.AppSolverE.SysGeometry);
            h.LineWidth = 1;
            h.Color = [1,0,0];
            xlabel("r(m)", "FontSize", 12)
            ylabel("z(m)", "FontSize", 12)
            title("mesh for electro static problem")

        end

        % Button pushed function: feildonmeshButton
        function feildonmeshButtonPushed(app, event)
            if isempty(app.AppSolverE)
                return
            end

            figure
            Ex = app.AppSolverE.Results.interpolateElectricField.Ex';
            Ey = app.AppSolverE.Results.interpolateElectricField.Ey';
            vnorm = sqrt(Ex.^2 + Ey.^2);
            quiver(app.AppSolverE.Results.Mesh.Nodes(1,:), ...
                app.AppSolverE.Results.Mesh.Nodes(2,:), ...
                Ex./vnorm, ...
                Ey./vnorm);
            hold on
            h = pdegplot(app.AppSolverE.SysGeometry);
            h.LineWidth = 1;
            h.Color = [1,0,0];
            xlabel("r(m)", "FontSize", 12)
            ylabel("z(m)", "FontSize", 12)
            title("electric field direction")
        end

        % Button pushed function: filedongridButton
        function filedongridButtonPushed(app, event)
            if isempty(app.AppSolverE)
                return
            end

            figure

            xq = min(app.AppSolverE.Results.Mesh.Nodes(1,:)): ...
                app.dxmEditField.Value:...
                max(app.AppSolverE.Results.Mesh.Nodes(1,:));
            yq = min(app.AppSolverE.Results.Mesh.Nodes(2,:)): ...
                app.dymEditField.Value:...
                max(app.AppSolverE.Results.Mesh.Nodes(2,:));

            [xq, yq] = meshgrid(xq, yq);
            xq = xq(:);
            yq = yq(:);

            Eintrp = interpolateElectricField(app.AppSolverE.Results,xq,yq);

            quiver(xq, yq, Eintrp.Ex, Eintrp.Ey, 4);
            hold on
            h = pdegplot(app.AppSolverE.SysGeometry);
            h.LineWidth = 1;
            h.Color = [1,0,0];
            xlabel("r(m)", "FontSize", 12)
            ylabel("z(m)", "FontSize", 12)
            title("electric field direction")
        end

        % Button pushed function: meshButton_2
        function meshButton_2Pushed(app, event)
            if isempty(app.AppSolverB)
                return
            end

            figure
            pdeplot(app.AppSolverB.Model.Mesh)
            hold on
            h = pdegplot(app.AppSolverB.SysGeometry);
            h.LineWidth = 1;
            h.Color = [1,0,0];
            xlabel("r(m)", "FontSize", 12)
            ylabel("z(m)", "FontSize", 12)
            title("mesh for magneto static problem")

        end

        % Button pushed function: feildonmeshButton_2
        function feildonmeshButton_2Pushed(app, event)
            if isempty(app.AppSolverB)
                return
            end

            figure
            Hx = app.AppSolverB.Results.MagneticField.Hx';
            Hy = app.AppSolverB.Results.MagneticField.Hy';
            vnorm = sqrt(Hx.^2 + Hy.^2);
            quiver(app.AppSolverB.Results.Mesh.Nodes(1,:), ...
                app.AppSolverB.Results.Mesh.Nodes(2,:), ...
                Hx./vnorm, ...
                Hy./vnorm);
            hold on
            h = pdegplot(app.AppSolverB.SysGeometry);
            h.LineWidth = 1;
            h.Color = [1,0,0];
            xlabel("r(m)", "FontSize", 12)
            ylabel("z(m)", "FontSize", 12)
            title("magnetic field")
        end

        % Button pushed function: filedongridButton_2
        function filedongridButton_2Pushed(app, event)
            if isempty(app.AppSolverB)
                return
            end

            figure

            xq = min(app.AppSolverB.Results.Mesh.Nodes(1,:)): ...
                app.dxmEditField_2.Value:...
                max(app.AppSolverB.Results.Mesh.Nodes(1,:));
            yq = min(app.AppSolverB.Results.Mesh.Nodes(2,:)): ...
                app.dymEditField_2.Value:...
                max(app.AppSolverB.Results.Mesh.Nodes(2,:));

            [xq, yq] = meshgrid(xq, yq);
            xq = xq(:);
            yq = yq(:);

            Bintrp = interpolateMagneticField(app.AppSolverB.Results,xq,yq);

            quiver(xq, yq, Bintrp.Hx, Bintrp.Hy, 4);
            hold on
            h = pdegplot(app.AppSolverB.SysGeometry);
            h.LineWidth = 1;
            h.Color = [1,0,0];
            xlabel("r(m)", "FontSize", 12)
            ylabel("z(m)", "FontSize", 12)
            title("magnetic field")
        end

        % Button pushed function: interpolateButton
        function interpolateButtonPushed(app, event)
            % interpolation point
            x = app.xcoordinatesmEditField.Value;
            y = app.ycoordinatesmEditField.Value;
            
            % interpolate electric field 
            if ~isempty(app.AppEC.ElectrostaticSolver)
                [Ex, Ey] = app.AppEC.interpE(x, y);

                if isnan(Ex) || isnan(Ey)
                    Ex = eps;
                    Ey = eps;
                end

                app.ExVmEditField.Value = Ex;
                app.EyVmEditField.Value = Ey;
            end

            % interpolate magnetic field 
            if ~isempty(app.AppEC.MagnetostaticSolver)
                [Bx, By] = app.AppEC.interpB(x, y);

                if isnan(Bx) || isnan(By)
                    Bx = eps;
                    By = eps;
                end

                app.BxTEditField.Value = Bx;
                app.ByTEditField.Value = By;
            end
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)

            if isempty(app.AppEC.MagnetostaticSolver) || isempty(app.AppEC.ElectrostaticSolver)
                return
            end 
            
             % compute diffusion coefficient
             chamberTemp = 10; % in eV
             D = app.AppEC.getDiffusionBohmCoef(chamberTemp);

             % compute gas density 
             efficiency = app.anodeefficienyEditField.Value;
             ionVlocity = app.ionexitvlocitymsEditField.Value;
             n = app.AppEC.getGasDensityUpperBound(efficiency, ionVlocity);

             % create UI table content
             data = {};
             for i = 1 : length(D)
                data = [data; {i, D(i), n(i)}];
             end
             app.UITable2.Data = data;
            
        end

        % Button pushed function: ExportresultsButton
        function ExportresultsButtonPushed(app, event)
            % export solver results into workspace 
            if ~isempty(app.AppSolverE) 
                assignin('base', 'electrostaticResults', app.AppSolverE.Results);
            end
            
            if ~isempty(app.AppSolverB) 
                assignin('base', 'magnetostaticResults', app.AppSolverB.Results);
            end
            
        end

        % Button pushed function: exportcsvButton
        function exportcsvButtonPushed(app, event)
 
            if isempty(app.AppSolverE) || isempty(app.AppSolverB) 
                return
            end

            % grid the space of simulation
            xq = min(app.AppSolverE.Results.Mesh.Nodes(1,:)): ...
                app.dxmEditField.Value:...
                max(app.AppSolverE.Results.Mesh.Nodes(1,:));
            yq = min(app.AppSolverE.Results.Mesh.Nodes(2,:)): ...
                app.dymEditField.Value:...
                max(app.AppSolverE.Results.Mesh.Nodes(2,:));

            [xq, yq] = meshgrid(xq, yq);
            xq = xq(:);
            yq = yq(:);

            % get electric and magnetic fields in all points 
            Eintrp = interpolateElectricField(app.AppSolverE.Results,xq,yq);
            Hintrp = interpolateMagneticField(app.AppSolverB.Results,xq,yq);

            mu = 1.2566370614E-6;
            Ex = Eintrp.Ex;
            Ey = Eintrp.Ey;
            Bx = mu * Hintrp.Hx;
            By = mu * Hintrp.Hy;

            % create data table 
            ides = 1:length(xq);
            data = [ides', xq, yq, Ex, Ey, Bx, By];
            T = array2table(data, 'VariableNames', {'ID', 'X', 'Y', 'Ex', 'Ey', 'Bx', 'By'});

            % get the file name from user 
            [fileName, filePath] = uiputfile({'*.csv', 'CSV Files (*.csv)'}, 'Save CSV File');
            if isequal(fileName, 0) || isequal(filePath, 0)
                return;
            end

            % save data into csv
            fullFileName = fullfile(filePath, fileName);
            writetable(T, fullFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EnviromentTab = uitab(app.TabGroup);
            app.EnviromentTab.Title = 'Enviroment';
            app.ThrusterPanel = uipanel(app.EnviromentTab);
            app.ThrusterPanel.Title = 'Thruster';
            app.CathodePanel = uipanel(app.ThrusterPanel);
            app.CathodePanel.Title = 'Cathode';
            app.Label = uilabel(app.CathodePanel);
            app.Label.Text = 'Voltage (V):';
            app.VoltageVEditField = uieditfield(app.CathodePanel, 'text');
            app.VoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @VoltageVEditFieldValueChanged, true);
            app.VoltageVEditField.Placeholder = 'Cathode tip voltage';
            app.RadiusmEditFieldLabel = uilabel(app.CathodePanel);
            app.RadiusmEditFieldLabel.Text = 'Radius (m):';
            app.RadiusmEditField = uieditfield(app.CathodePanel, 'text');
            app.RadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusmEditFieldValueChanged, true);
            app.RadiusmEditField.Placeholder = 'Radius of cathode ';
            app.HeightmEditFieldLabel = uilabel(app.CathodePanel);
            app.HeightmEditFieldLabel.Text = 'Height (m):';
            app.HeightmEditField = uieditfield(app.CathodePanel, 'text');
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.HeightmEditField.Placeholder = 'Height of cathode tip from thruster surface ';
            app.IsinmiddleCheckBox = uicheckbox(app.CathodePanel);
            app.IsinmiddleCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsinmiddleCheckBoxValueChanged, true);
            app.IsinmiddleCheckBox.Text = 'Is in middle';
            app.ChambersPanel = uipanel(app.ThrusterPanel);
            app.ChambersPanel.Title = 'Chambers';
            app.UITable = uitable(app.ChambersPanel);
            app.UITable.ColumnName = {'Width(m)'; 'Depth(m)'; 'MRF'; 'MRT'; 'MP(A)'; 'SR(m)'; 'AW(m)'; 'AV(Volt)'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddButton = uibutton(app.ChambersPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.EditButton = uibutton(app.ChambersPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteButton = uibutton(app.ChambersPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.FinalWidthLabel = uilabel(app.ThrusterPanel);
            app.FinalWidthLabel.Text = 'Final Width (m):';
            app.FinalWidthmEditField = uieditfield(app.ThrusterPanel, 'text');
            app.FinalWidthmEditField.ValueChangedFcn = createCallbackFcn(app, @FinalWidthmEditFieldValueChanged, true);
            app.FinalWidthmEditField.Placeholder = 'Width of thruster''s end part ';
            app.EnvHeightLabel = uilabel(app.EnviromentTab);
            app.EnvHeightLabel.Text = 'Env Height (m):';
            app.EnvHeightmEditField = uieditfield(app.EnviromentTab, 'text');
            app.EnvHeightmEditField.ValueChangedFcn = createCallbackFcn(app, @EnvHeightmEditFieldValueChanged, true);
            app.EnvHeightmEditField.Placeholder = 'Enviroment height from thruster surface for simulation';
            app.EnvRadiusmEditFieldLabel = uilabel(app.EnviromentTab);
            app.EnvRadiusmEditFieldLabel.Text = 'Env Radius (m):';
            app.EnvRadiusmEditField = uieditfield(app.EnviromentTab, 'text');
            app.EnvRadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @EnvRadiusmEditFieldValueChanged, true);
            app.EnvRadiusmEditField.Placeholder = 'Enviroment radius for simulation';
            app.GeneratemeshButton = uibutton(app.EnviromentTab, 'push');
            app.GeneratemeshButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratemeshButtonPushed, true);
            app.GeneratemeshButton.Text = 'Generate mesh';
            app.Plot3DButton = uibutton(app.EnviromentTab, 'push');
            app.Plot3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3DButtonPushed, true);
            app.Plot3DButton.Text = 'Plot3D';
            app.Plot2DButton = uibutton(app.EnviromentTab, 'push');
            app.Plot2DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2DButtonPushed, true);
            app.Plot2DButton.Text = 'Plot2D';
            app.SolverTab = uitab(app.TabGroup);
            app.SolverTab.Title = 'Solver';
            app.EParametersPanel = uipanel(app.SolverTab);
            app.EParametersPanel.Title = 'E Parameters';
            app.DetailLengthLabel = uilabel(app.EParametersPanel);
            app.DetailLengthLabel.Text = 'Detail Length(m):';
            app.DetailLengthmEditField = uieditfield(app.EParametersPanel, 'numeric');
            app.DetailLengthmEditField.Value = 0.002;
            app.HminLabel = uilabel(app.EParametersPanel);
            app.HminLabel.Text = 'Hmin(m):';
            app.HminmEditField = uieditfield(app.EParametersPanel, 'numeric');
            app.HminmEditField.Value = 0.002;
            app.HmaxLabel = uilabel(app.EParametersPanel);
            app.HmaxLabel.Text = 'Hmax(m):';
            app.HmaxmEditField = uieditfield(app.EParametersPanel, 'numeric');
            app.HmaxmEditField.Value = 0.04;
            app.HgradLabel = uilabel(app.EParametersPanel);
            app.HgradLabel.Text = 'Hgrad:';
            app.HgradEditField = uieditfield(app.EParametersPanel, 'numeric');
            app.HgradEditField.Value = 1.3;
            app.SolveButton = uibutton(app.EParametersPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.BParametersPanel = uipanel(app.SolverTab);
            app.BParametersPanel.Title = 'B Parameters';
            app.DetailLengthmEditField_2Label = uilabel(app.BParametersPanel);
            app.DetailLengthmEditField_2Label.Text = 'Detail Length(m):';
            app.DetailLengthmEditField_2 = uieditfield(app.BParametersPanel, 'numeric');
            app.DetailLengthmEditField_2.Value = 0.003;
            app.HminmEditField_2Label = uilabel(app.BParametersPanel);
            app.HminmEditField_2Label.Text = 'Hmin(m):';
            app.HminmEditField_2 = uieditfield(app.BParametersPanel, 'numeric');
            app.HminmEditField_2.Value = 0.002;
            app.HmaxmEditField_2Label = uilabel(app.BParametersPanel);
            app.HmaxmEditField_2Label.Text = 'Hmax(m):';
            app.HmaxmEditField_2 = uieditfield(app.BParametersPanel, 'numeric');
            app.HmaxmEditField_2.Value = 0.04;
            app.HgradEditField_2Label = uilabel(app.BParametersPanel);
            app.HgradEditField_2Label.Text = 'Hgrad:';
            app.HgradEditField_2 = uieditfield(app.BParametersPanel, 'numeric');
            app.HgradEditField_2.Value = 1.3;
            app.SolveButton_2 = uibutton(app.BParametersPanel, 'push');
            app.SolveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SolveButton_2Pushed, true);
            app.SolveButton_2.Text = 'Solve';
            app.CorePermeabilityEditFieldLabel = uilabel(app.BParametersPanel);
            app.CorePermeabilityEditFieldLabel.Text = 'CorePermeability:';
            app.CorePermeabilityEditField = uieditfield(app.BParametersPanel, 'numeric');
            app.CorePermeabilityEditField.Value = 5000;
            app.EplotPanel = uipanel(app.SolverTab);
            app.EplotPanel.Title = 'E plot';
            app.meshButton = uibutton(app.EplotPanel, 'push');
            app.meshButton.ButtonPushedFcn = createCallbackFcn(app, @meshButtonPushed, true);
            app.meshButton.Text = 'mesh';
            app.feildonmeshButton = uibutton(app.EplotPanel, 'push');
            app.feildonmeshButton.ButtonPushedFcn = createCallbackFcn(app, @feildonmeshButtonPushed, true);
            app.feildonmeshButton.Text = 'feild on mesh';
            app.dxLabel = uilabel(app.EplotPanel);
            app.dxLabel.Text = 'dx(m):';
            app.dxmEditField = uieditfield(app.EplotPanel, 'numeric');
            app.dxmEditField.Value = 0.01;
            app.dymEditFieldLabel = uilabel(app.EplotPanel);
            app.dymEditFieldLabel.Text = 'dy(m):';
            app.dymEditField = uieditfield(app.EplotPanel, 'numeric');
            app.dymEditField.Value = 0.01;
            app.filedongridButton = uibutton(app.EplotPanel, 'push');
            app.filedongridButton.ButtonPushedFcn = createCallbackFcn(app, @filedongridButtonPushed, true);
            app.filedongridButton.Text = 'filed on grid';
            app.BplotPanel = uipanel(app.SolverTab);
            app.BplotPanel.Title = 'B plot';
            app.meshButton_2 = uibutton(app.BplotPanel, 'push');
            app.meshButton_2.ButtonPushedFcn = createCallbackFcn(app, @meshButton_2Pushed, true);
            app.meshButton_2.Text = 'mesh';
            app.feildonmeshButton_2 = uibutton(app.BplotPanel, 'push');
            app.feildonmeshButton_2.ButtonPushedFcn = createCallbackFcn(app, @feildonmeshButton_2Pushed, true);
            app.feildonmeshButton_2.Text = 'feild on mesh';
            app.dxmEditField_2Label = uilabel(app.BplotPanel);
            app.dxmEditField_2Label.Text = 'dx(m):';
            app.dxmEditField_2 = uieditfield(app.BplotPanel, 'numeric');
            app.dxmEditField_2.Value = 0.01;
            app.dymEditField_2Label = uilabel(app.BplotPanel);
            app.dymEditField_2Label.Text = 'dy(m):';
            app.dymEditField_2 = uieditfield(app.BplotPanel, 'numeric');
            app.dymEditField_2.Value = 0.01;
            app.filedongridButton_2 = uibutton(app.BplotPanel, 'push');
            app.filedongridButton_2.ButtonPushedFcn = createCallbackFcn(app, @filedongridButton_2Pushed, true);
            app.filedongridButton_2.Text = 'filed on grid';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.interpolationPanel = uipanel(app.ResultsTab);
            app.interpolationPanel.Title = 'interpolation';
            app.xcoordinatesLabel = uilabel(app.interpolationPanel);
            app.xcoordinatesLabel.Text = 'x coordinates(m):';
            app.xcoordinatesmEditField = uieditfield(app.interpolationPanel, 'numeric');
            app.ycoordinatesLabel = uilabel(app.interpolationPanel);
            app.ycoordinatesLabel.Text = 'y coordinates(m):';
            app.ycoordinatesmEditField = uieditfield(app.interpolationPanel, 'numeric');
            app.ExLabel = uilabel(app.interpolationPanel);
            app.ExLabel.Text = 'Ex(V/m):';
            app.ExVmEditField = uieditfield(app.interpolationPanel, 'numeric');
            app.ExVmEditField.Editable = 'off';
            app.interpolateButton = uibutton(app.interpolationPanel, 'push');
            app.interpolateButton.ButtonPushedFcn = createCallbackFcn(app, @interpolateButtonPushed, true);
            app.interpolateButton.Text = 'interpolate';
            app.EyVmEditFieldLabel = uilabel(app.interpolationPanel);
            app.EyVmEditFieldLabel.Text = 'Ey(V/m):';
            app.EyVmEditField = uieditfield(app.interpolationPanel, 'numeric');
            app.EyVmEditField.Editable = 'off';
            app.BxTEditFieldLabel = uilabel(app.interpolationPanel);
            app.BxTEditFieldLabel.Text = 'Bx(T):';
            app.BxTEditField = uieditfield(app.interpolationPanel, 'numeric');
            app.BxTEditField.Editable = 'off';
            app.ByTEditFieldLabel = uilabel(app.interpolationPanel);
            app.ByTEditFieldLabel.Text = 'By(T):';
            app.ByTEditField = uieditfield(app.interpolationPanel, 'numeric');
            app.ByTEditField.Editable = 'off';
            app.coefficientsPanel = uipanel(app.ResultsTab);
            app.coefficientsPanel.Title = 'coefficients';
            app.UITable2 = uitable(app.coefficientsPanel);
            app.UITable2.ColumnName = {'Chamber ID'; 'Diffusion Coefficient (m2/s)'; 'Gas Density UB (1/m3)'};
            app.UITable2.RowName = {};
            app.computeButton = uibutton(app.coefficientsPanel, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.anodeefficienyEditFieldLabel = uilabel(app.coefficientsPanel);
            app.anodeefficienyEditFieldLabel.Text = 'anode efficieny';
            app.anodeefficienyEditField = uieditfield(app.coefficientsPanel, 'numeric');
            app.anodeefficienyEditField.Value = 0.2;
            app.ionexitvlocitymsEditFieldLabel = uilabel(app.coefficientsPanel);
            app.ionexitvlocitymsEditFieldLabel.Text = 'ion exit vlocity(m/s)';
            app.ionexitvlocitymsEditField = uieditfield(app.coefficientsPanel, 'numeric');
            app.ionexitvlocitymsEditField.Value = 16000;
            app.ExportresultsButton = uibutton(app.ResultsTab, 'push');
            app.ExportresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportresultsButtonPushed, true);
            app.ExportresultsButton.Text = 'Export results';
            app.dxmEditField_3Label = uilabel(app.ResultsTab);
            app.dxmEditField_3Label.Text = 'dx(m):';
            app.dxmEditField_3 = uieditfield(app.ResultsTab, 'numeric');
            app.dxmEditField_3.Value = 0.01;
            app.dymEditField_3Label = uilabel(app.ResultsTab);
            app.dymEditField_3Label.Text = 'dy(m):';
            app.dymEditField_3 = uieditfield(app.ResultsTab, 'numeric');
            app.dymEditField_3.Value = 0.01;
            app.exportcsvButton = uibutton(app.ResultsTab, 'push');
            app.exportcsvButton.ButtonPushedFcn = createCallbackFcn(app, @exportcsvButtonPushed, true);
            app.exportcsvButton.Text = 'export csv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end